classdef GUIDECV_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uitoolbar2            matlab.ui.container.Toolbar
        toggleToolZoomOut     matlab.ui.container.toolbar.ToggleTool
        toggleToolPan         matlab.ui.container.toolbar.ToggleTool
        toggleToolRotate      matlab.ui.container.toolbar.ToggleTool
        toggleToolZoomIn      matlab.ui.container.toolbar.ToggleTool
        LabelForeground       matlab.ui.control.Label
        LabelVanishingPoint   matlab.ui.control.Label
        LabelInnerRectangle   matlab.ui.control.Label
        LabelSelectImage      matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        iconHT4               matlab.ui.control.Image
        iconHT3               matlab.ui.control.Image
        iconHT2               matlab.ui.control.Image
        iconHT1               matlab.ui.control.Image
        buttonStart           matlab.ui.control.Button
        buttonReset           matlab.ui.control.Button
        buttonQuit            matlab.ui.control.Button
        buttonForeground      matlab.ui.control.Button
        buttonVanishingPoint  matlab.ui.control.Button
        buttonInnerRectangle  matlab.ui.control.Button
        buttonSelectImage     matlab.ui.control.Button
        axes3                 matlab.ui.control.UIAxes
    end

    % define variables to be used acrossed the app
    properties (Access = public)
        % to save the current UI state
        CurrentState

        % image loaded
        Img

        % size of the image
        X1_Img
        X2_Img
        X3_Img
        SizeX
        SizeY

        VanishingPoint
        EstimatedVertex

        Foreground
        Background

        % Inner rectangle
        Pos1
        Pos2
        Pos3
        Pos4

        Max_y
        Max_x
        Min_y
        Min_x

        % Foreground rectangle
        PosFG1
        PosFG2
        PosFG3
        PosFG4

        Max_y_FG
        Max_x_FG
        Min_y_FG
        Min_x_FG
        
        Test
        VertexFG
        OutFG
        ObjFG
        PicNum
    end

    methods (Access = public)
        function resetInteractions2(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions. It also resets the figure interactions.
            
            % Find all tools to reset. Exclude the tool associated
            % with the event.
            interactiveTools = [app.toggleToolZoomIn, app.toggleToolZoomOut, app.toggleToolPan, app.toggleToolRotate];
            interactiveTools(event.Source == interactiveTools) = [];
            
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
            
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        % update UI state depending on which button is clicked
        function updateButtonStates(app)
            switch app.CurrentState
                case 'State1'
                    app.buttonSelectImage.Enable = 'on';
                    app.LabelSelectImage.Visible = 'on';
                    
                    % Visibility for the lightbulb icon
                    app.iconHT1.Visible ="on";
                    app.iconHT2.Visible ="off";
                    app.iconHT3.Visible ="off";
                    app.iconHT4.Visible ="off";

                    % Visibility icons
                    app.LabelInnerRectangle.Visible = 'off';
                    app.LabelVanishingPoint.Visible = "off";
                    app.LabelForeground.Visible = "off";
    
                    % Visibility buttons
                    app.buttonInnerRectangle.Enable = 'off';     
                    app.buttonVanishingPoint.Enable = 'off';
                    app.buttonForeground.Enable = 'off'; 
                    app.buttonStart.Enable = 'off'; 
                    app.buttonReset.Enable = 'off'; 
                    app.buttonQuit.Enable = 'on';
                    
                case 'State2'

                    app.iconHT1.Visible ="off";
                    app.iconHT2.Visible ="on";
                    app.iconHT3.Visible ="off";
                    app.iconHT4.Visible ="off";

                    app.buttonSelectImage.Enable = 'off';
                    app.LabelSelectImage.Visible = 'off';

    
                    app.buttonInnerRectangle.Enable = 'on';
                    app.LabelInnerRectangle.Visible = 'on';
    
                    app.buttonVanishingPoint.Enable = 'off';     
                    app.buttonForeground.Enable = 'off';
                    app.buttonStart.Enable = 'off';
                    app.buttonReset.Enable = 'on';
                    app.buttonQuit.Enable = 'on';
                    
                case 'State3'
                    app.iconHT1.Visible ="off";
                    app.iconHT2.Visible ="off";
                    app.iconHT3.Visible ="on";
                    app.iconHT4.Visible ="off";

                    app.buttonSelectImage.Enable = 'off';
                    app.LabelSelectImage.Visible = 'off';

    
                    app.buttonInnerRectangle.Enable = 'off';
                    app.LabelInnerRectangle.Visible = 'off';
    
                    app.buttonVanishingPoint.Enable = 'on';
                    app.LabelVanishingPoint.Visible = 'on';
    
                    app.buttonForeground.Enable = 'off'; 
                    app.buttonStart.Enable = 'off';
                    app.buttonReset.Enable = 'on';
                    app.buttonQuit.Enable = 'on';
                    
                case 'State4'
                    app.iconHT1.Visible ="off";
                    app.iconHT2.Visible ="off";
                    app.iconHT3.Visible ="off";
                    app.iconHT4.Visible ="off";

                    app.buttonSelectImage.Enable = 'off';
                    app.LabelSelectImage.Visible = 'off';

    
                    app.buttonInnerRectangle.Enable = 'off';
                    app.LabelInnerRectangle.Visible = 'off';
    
                    app.buttonVanishingPoint.Enable = 'off';
                    app.LabelVanishingPoint.Visible = 'off';
    
                    app.buttonForeground.Enable = 'on';

                    app.buttonStart.Enable = 'on';
                    app.buttonStart.Icon = './LaunchAnalysis.GIF';

                    app.buttonReset.Enable = 'on';

                    app.buttonQuit.Enable = 'on';
                case 'State5'
                    app.iconHT1.Visible ="off";
                    app.iconHT2.Visible ="off";
                    app.iconHT3.Visible ="off";
                    app.iconHT4.Visible ="on";

                    app.buttonSelectImage.Enable = 'off';
                    app.LabelSelectImage.Visible = 'off';

    
                    app.buttonInnerRectangle.Enable = 'off';
                    app.LabelInnerRectangle.Visible = 'off';
    
                    app.buttonVanishingPoint.Enable = 'off';
                    app.LabelVanishingPoint.Visible = 'off';
    
                    app.buttonForeground.Enable = 'off';

                    app.buttonStart.Enable = 'on';
                    app.buttonStart.Icon = './LaunchAnalysis.GIF';

                    app.buttonReset.Enable = 'on';
                    app.buttonQuit.Enable = 'on';
            end
        end

        function defineStates(app)
            app.CurrentState = 'State1';
            app.updateButtonStates();
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.toggleToolZoomIn, app.toggleToolZoomOut, app.toggleToolPan, app.toggleToolRotate];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app, varargin)
            % This is called at the start of the UI
            
            % define initial state.
            app.defineStates();

            % display the app
            movegui(app.figure1, 'onscreen');

            % make the axes invisible
            set(app.axes3,'visible','off');      


        end

        % Button pushed function: buttonSelectImage
        function buttonSelectImage_Callback(app, event)
            app.CurrentState = 'State2';
            
            class(app)
            
            % Open file explorer
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'select a picture');
            str=[pathname filename];

            % Load the image
            app.Img = imread(str);

            % Error handling
            if isequal(filename,0)||isequal(pathname,0)
                warndlg('A picture is required, please select one.','warning');
                return;
            else
                app.Img = imread(str);
                imshow(app.Img);
            end

            % Transition to next state
            app.updateButtonStates();
        end

        % Button pushed function: buttonQuit
        function buttonQuit_Callback(app, event)
            close all;
        end

        % Button pushed function: buttonReset
        function buttonReset_Callback(app, event)
            app.CurrentState = 'State1';
            
            % Reset axes
            axes(app.axes3); %Specify the coordinates that need to be cleared
            cla reset;
            set(app.axes3,'visible','off');
    
            app.updateButtonStates();
        end

        % Button pushed function: buttonInnerRectangle
        function buttonInnerRectangle_Callback(app, event)
            % --- Executes on button press in pushbutton4.
            app.CurrentState = 'State3';
            

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes3 = gca;%Get the current axis
            h=imrect;%The mouse turns into a cross to select the region of interest
            
            
            %There will be a rectangular box that can be dragged and resized
            
            pos=getPosition(h);%pos has four values，are the coordinates of the upper left corner of the rectangular box x y and the width and height of the box
            hold on

            app.Pos1=[pos(1),pos(2)]

            app.Pos2=[pos(1)+pos(3),pos(2)]
            app.Pos3=[pos(1)+pos(3),pos(2)+pos(4)]
            app.Pos4=[pos(1),pos(2)+pos(4)]

            app.Min_y=pos(2);
            app.Max_y=pos(2)+pos(4);
            app.Min_x=pos(1);
            app.Max_x=pos(1)+pos(3);
            
            app.updateButtonStates();
        end

        % Button pushed function: buttonVanishingPoint
        function buttonVanishingPoint_Callback(app, event)
            % --- Executes on button press in pushbutton5.
            app.CurrentState = 'State4';
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.figure1.HandleVisibility;        % Current status
            app.figure1.HandleVisibility = 'on'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.figure1)       % Make fig current
            [x,y] = ginput(1);
            set(gcf, 'Pointer', 'arrow');
            drawnow;
            app.figure1.HandleVisibility = fhv;        % return original state
            
            hold on
            a=min(x);
            b=min(y);%Get the pixel coordinates of a point clicked by the mouse, x saves the abscissa vector, y saves the ordinate vector
            a=floor(a)
            b=floor(b)
            T=[a,b]

            app.VanishingPoint=[b,a];

            [app.X1_Img,app.X2_Img,app.X3_Img]=size(app.Img)
            app.SizeX = app.X2_Img
            app.SizeY = app.X1_Img
            
            x_vp = a;
            y_vp = b;
            
            coordinat_sp(1,:)= app.Pos4; 
            coordinat_sp(2,:) = app.Pos3; 
            coordinat_sp(3,:) = app.Pos2; 
            coordinat_sp(4,:) = app.Pos1; 
            
            
            % gradient for the foreground
            for i =1:4
                gradient(i) = (coordinat_sp(i,2)-y_vp)/(coordinat_sp(i,1)-x_vp)
            end

            app.EstimatedVertex = zeros(2,12);
            app.EstimatedVertex(:,1) = app.Pos4;
            app.EstimatedVertex(:,2) = app.Pos3;
            app.EstimatedVertex(:,8) = app.Pos2;
            app.EstimatedVertex(:,7) = app.Pos1;
            
            app.EstimatedVertex(:,3) = [(app.SizeY-y_vp)/gradient(1) + x_vp; app.SizeY];
            app.EstimatedVertex(:,5) = [1; (1-x_vp)*gradient(1) + y_vp];
            app.EstimatedVertex(:,4) = [(app.SizeY-y_vp)/gradient(2) + x_vp; app.SizeY];
            app.EstimatedVertex(:,6) = [app.SizeX; (app.SizeX-x_vp)*gradient(2) + y_vp];
            app.EstimatedVertex(:,10) = [(1-y_vp)/gradient(3) + x_vp; 1];
            app.EstimatedVertex(:,12) = [app.SizeX; (app.SizeX-x_vp)*gradient(3) + y_vp];
            app.EstimatedVertex(:,9) = [(1-y_vp)/gradient(4) + x_vp; 1];
            app.EstimatedVertex(:,11) = [1; (1-x_vp)*gradient(4) + y_vp];
            
            
            for i = 1:12
                plot([app.EstimatedVertex(1,i),x_vp],[app.EstimatedVertex(2,i),y_vp],'r-','lineWidth',3)
                text(app.EstimatedVertex(1,i),app.EstimatedVertex(2,i),num2str(i))
            end
            
             plot([app.EstimatedVertex(1,5),app.EstimatedVertex(1,11)],[app.EstimatedVertex(2,5),app.EstimatedVertex(2,11)],'w-','lineWidth',3)
             plot([app.EstimatedVertex(1,9),app.EstimatedVertex(1,10)],[app.EstimatedVertex(2,9),app.EstimatedVertex(2,10)],'w-','lineWidth',3)
             plot([app.EstimatedVertex(1,6),app.EstimatedVertex(1,12)],[app.EstimatedVertex(2,6),app.EstimatedVertex(2,12)],'w-','lineWidth',3)
             plot([app.EstimatedVertex(1,3),app.EstimatedVertex(1,4)],[app.EstimatedVertex(2,3),app.EstimatedVertex(2,4)],'w-','lineWidth',3)
            
             app.PicNum=ceil(max(size(app.Img(:,:)))/5);
             app.Foreground = {};
            % Reset the interactive tools and figure state
            app.resetInteractions2(event);

            app.updateButtonStates();
        end

        % Button pushed function: buttonStart
        function buttonStart_Callback(app, event)
            % --- Executes on button press in pushbutton7.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            hold off
            app.SizeX=floor(app.SizeX);
            app.SizeY=floor(app.SizeY);
            max_y=floor(app.Max_y);
            max_x=floor(app.Max_x);
            min_y=floor(app.Min_y);
            min_x=floor(app.Min_x);
            app.VanishingPoint=floor(app.VanishingPoint);
            box=[min_y,max_y,min_x,max_x];
            % mx=0;my=0;mz=0;rx=0;ry=0;rz=0;
            % geo=[mx,my,mz,rx,ry,rz];
            % 
            % 
            %  [out,app.Test]=Tour_into_the_3d_picture(app.Img,app.VanishingPoint,box);
            %  f = figure;
            %  surface_3d(out)
            %  hold on
            %  if numel(app.Foreground) ~= 0
            %        [X,Y,Z,C,outputAlpha] = surfaceFG_3d(app.Foreground,out,app.VertexFG,app.ObjFG,app.Test,app.PicNum);
            %         h = surface(X,Y,Z,C);
            %         set(h,'LineStyle','none');
            %         h.AlphaData = outputAlpha;
            %         h.FaceAlpha = 'flat';
            %  end
            %   cameratoolbar('NoReset')
            %  tb = cameratoolbar(f);
            %  view(0,-80)
            %  setCamera(out)
            %  axis off
            [walls,scale]=Tour_into_the_3d_picture(app.Img,app.VanishingPoint,box);
            room3D = Navigation3D(walls);
            room3D.renderForeground(app.Foreground,app.ObjFG,app.VertexFG,scale)
        end

        % Button pushed function: buttonForeground
        function buttonForeground_Callback(app, event)

            % define state
            app.CurrentState = 'State5';
            
            h=imrect;

            posFG=getPosition(h);
            hold on

            % get corners 
            app.PosFG1=[posFG(1),posFG(2)]
            app.PosFG2=[posFG(1)+posFG(3),posFG(2)]
            app.PosFG3=[posFG(1)+posFG(3),posFG(2)+posFG(4)]
            app.PosFG4=[posFG(1),posFG(2)+posFG(4)]
            app.Min_y_FG=posFG(2);
            app.Max_y_FG=posFG(2)+posFG(4);
            app.Min_x_FG=posFG(1);
            app.Max_x_FG=posFG(1)+posFG(3);

            [app.Foreground, app.Img]= maskBackground(app.Img, app.Min_y_FG,app.Max_y_FG,app.Min_x_FG,app.Max_x_FG);
            [app.VertexFG,app.ObjFG]  = foregroundobj(app.Foreground,app.VanishingPoint,app.EstimatedVertex,app.Min_y_FG,app.Max_y_FG,app.Min_x_FG,app.Max_x_FG);
            
            app.updateButtonStates();
        end

        % Callback function: toggleToolRotate
        function toggleToolRotate_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable rotation based on the
            % tool's current state.
            state = app.toggleToolRotate.State;
            rotate3d(app.figure1, char(state));
        end

        % Callback function: toggleToolPan
        function toggleToolPan_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.toggleToolPan.State;
            pan(app.figure1, char(state));
        end

        % Callback function: toggleToolZoomOut
        function toggleToolZoomOut_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.toggleToolZoomOut.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: toggleToolZoomIn
        function toggleToolZoomIn_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.toggleToolZoomIn.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIDECV';
            app.figure1.Tag = 'figure1';
            app.uitoolbar2 = uitoolbar(app.figure1);
            app.uitoolbar2.Tag = 'uitoolbar2';
            app.toggleToolZoomOut = uitoggletool(app.uitoolbar2);
            app.toggleToolZoomOut.Tag = 'uitoggletool4';
            app.toggleToolZoomOut.Tooltip = '缩小';
            app.toggleToolZoomOut.ClickedCallback = createCallbackFcn(app, @toggleToolZoomOut_ClickedCallback, true);
            app.toggleToolZoomOut.Icon = 'uitoggletool4_image.png';
            app.toggleToolPan = uitoggletool(app.uitoolbar2);
            app.toggleToolPan.Tag = 'uitoggletool5';
            app.toggleToolPan.Tooltip = '平移';
            app.toggleToolPan.ClickedCallback = createCallbackFcn(app, @toggleToolPan_ClickedCallback, true);
            app.toggleToolPan.Icon = 'uitoggletool5_image.png';
            app.toggleToolRotate = uitoggletool(app.uitoolbar2);
            app.toggleToolRotate.Tag = 'uitoggletool6';
            app.toggleToolRotate.Tooltip = '三维旋转';
            app.toggleToolRotate.ClickedCallback = createCallbackFcn(app, @toggleToolRotate_ClickedCallback, true);
            app.toggleToolRotate.Icon = 'uitoggletool6_image.png';
            app.toggleToolZoomIn = uitoggletool(app.uitoolbar2);
            app.toggleToolZoomIn.Tag = 'uitoggletool3';
            app.toggleToolZoomIn.Tooltip = '放大';
            app.toggleToolZoomIn.ClickedCallback = createCallbackFcn(app, @toggleToolZoomIn_ClickedCallback, true);
            app.toggleToolZoomIn.Icon = 'uitoggletool3_image.png';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.Panel = uipanel(app.figure1);
            app.buttonSelectImage = uibutton(app.Panel, 'push');
            app.buttonSelectImage.ButtonPushedFcn = createCallbackFcn(app, @buttonSelectImage_Callback, true);
            app.buttonSelectImage.Tag = 'pushbutton1';
            app.buttonSelectImage.Icon = fullfile(pathToMLAPP, 'SelectImage.svg');
            app.buttonSelectImage.Text = 'Select Image';
            app.buttonInnerRectangle = uibutton(app.Panel, 'push');
            app.buttonInnerRectangle.ButtonPushedFcn = createCallbackFcn(app, @buttonInnerRectangle_Callback, true);
            app.buttonInnerRectangle.Tag = 'pushbutton4';
            app.buttonInnerRectangle.Icon = fullfile(pathToMLAPP, 'InnerRectangle_v2.jpeg');
            app.buttonInnerRectangle.Text = 'Inner rectangle';
            app.buttonVanishingPoint = uibutton(app.Panel, 'push');
            app.buttonVanishingPoint.ButtonPushedFcn = createCallbackFcn(app, @buttonVanishingPoint_Callback, true);
            app.buttonVanishingPoint.Tag = 'pushbutton5';
            app.buttonVanishingPoint.Icon = fullfile(pathToMLAPP, 'VanishingPoint.PNG');
            app.buttonVanishingPoint.Text = 'Vanishing point';
            app.buttonForeground = uibutton(app.Panel, 'push');
            app.buttonForeground.ButtonPushedFcn = createCallbackFcn(app, @buttonForeground_Callback, true);
            app.buttonForeground.Tag = 'pushbutton8';
            app.buttonForeground.Icon = fullfile(pathToMLAPP, 'ForeGround.PNG');
            app.buttonForeground.Text = 'Foreground';
            app.buttonQuit = uibutton(app.Panel, 'push');
            app.buttonQuit.ButtonPushedFcn = createCallbackFcn(app, @buttonQuit_Callback, true);
            app.buttonQuit.Tag = 'pushbutton2';
            app.buttonQuit.Text = 'Quit';
            app.buttonReset = uibutton(app.Panel, 'push');
            app.buttonReset.ButtonPushedFcn = createCallbackFcn(app, @buttonReset_Callback, true);
            app.buttonReset.Tag = 'pushbutton3';
            app.buttonReset.Text = 'Reset';
            app.buttonStart = uibutton(app.Panel, 'push');
            app.buttonStart.ButtonPushedFcn = createCallbackFcn(app, @buttonStart_Callback, true);
            app.buttonStart.Tag = 'pushbutton7';
            app.buttonStart.Icon = fullfile(pathToMLAPP, 'LaunchAnalysis.PNG');
            app.buttonStart.Text = 'Launch Analysis';
            app.iconHT1 = uiimage(app.Panel);
            app.iconHT1.ImageSource = fullfile(pathToMLAPP, 'iconLightbulb-removebg-preview.png');
            app.iconHT2 = uiimage(app.Panel);
            app.iconHT2.ImageSource = fullfile(pathToMLAPP, 'iconLightbulb-removebg-preview.png');
            app.iconHT3 = uiimage(app.Panel);
            app.iconHT3.ImageSource = fullfile(pathToMLAPP, 'iconLightbulb-removebg-preview.png');
            app.iconHT4 = uiimage(app.Panel);
            app.iconHT4.ImageSource = fullfile(pathToMLAPP, 'iconLightbulb-removebg-preview.png');
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.figure1);
            app.LabelSelectImage = uilabel(app.figure1);
            app.LabelSelectImage.Text = 'Click to select image and proceed';
            app.LabelInnerRectangle = uilabel(app.figure1);
            app.LabelInnerRectangle.Text = 'Click to select inner rectangle';
            app.LabelVanishingPoint = uilabel(app.figure1);
            app.LabelVanishingPoint.Text = 'Select vanishing point on the image';
            app.LabelForeground = uilabel(app.figure1);
            app.LabelForeground.Text = 'Select foreground object';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDECV_App_v2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end